classdef ModelingTECv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ThermistortempEditField         matlab.ui.control.NumericEditField
        ThermistortempEditFieldLabel    matlab.ui.control.Label
        HeatCoolLamp                    matlab.ui.control.Lamp
        HeatCoolLampLabel               matlab.ui.control.Label
        PWMEditField                    matlab.ui.control.NumericEditField
        PWMEditFieldLabel               matlab.ui.control.Label
        TECtempEditField                matlab.ui.control.NumericEditField
        TECtempEditFieldLabel           matlab.ui.control.Label
        ControlButtonGroup              matlab.ui.container.ButtonGroup
        ThermalMassButton               matlab.ui.control.ToggleButton
        ProportionalButton              matlab.ui.control.ToggleButton
        ManualButton                    matlab.ui.control.ToggleButton
        TECpowerSwitch                  matlab.ui.control.Switch
        TECpowerSwitchLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ManualTab                       matlab.ui.container.Tab
        HCSwitch                        matlab.ui.control.Switch
        HCSwitchLabel                   matlab.ui.control.Label
        PWMSlider                       matlab.ui.control.Slider
        PWMSliderLabel                  matlab.ui.control.Label
        Proportional                    matlab.ui.container.Tab
        SetTemperatureEditField         matlab.ui.control.NumericEditField
        K_pSlider                       matlab.ui.control.Slider
        K_pNumber                       matlab.ui.control.NumericEditField
        HeatLossConstant                matlab.ui.control.Slider
        HeatLossNumber                  matlab.ui.control.NumericEditField
        HeatLossLabel                   matlab.ui.control.Label
        SetTemperatureEditFieldLabel    matlab.ui.control.Label
        K_pSliderLabel                  matlab.ui.control.Label
        ProportionalwmassTab            matlab.ui.container.Tab
        mass1Slider                     matlab.ui.control.Slider
        mass1SliderLabel                matlab.ui.control.Label
        HeatLossNumber_2                matlab.ui.control.NumericEditField
        HeatLossLabel_2                 matlab.ui.control.Label
        HeatLossConstant_2              matlab.ui.control.Slider
        K_pNumber_2                     matlab.ui.control.NumericEditField
        K_pSlider_2                     matlab.ui.control.Slider
        K_pSlider_2Label                matlab.ui.control.Label
        SetTemperatureEditField_2       matlab.ui.control.NumericEditField
        SetTemperatureEditField_2Label  matlab.ui.control.Label
        RunSwitch                       matlab.ui.control.Switch
        RunSwitchLabel                  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TmPlot                          matlab.ui.control.UIAxes
        PWMPlot                         matlab.ui.control.UIAxes
        TPlot                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        TData % TEC temperature array
        TmData  % Mass temperature array
        RoomTemp = 20; % Used in heat loss
        Array_Start = 2;  % used in wrap around for strip chart
        Time;    % time array
        PWMData;   % PWM array
        PWM_Now;    % current PWM value
        ValuesToTrack=200;  % length of strip chart
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TData = zeros(1,app.ValuesToTrack);
            app.TData(:) = app.RoomTemp;
            app.TmData = zeros(1,app.ValuesToTrack);
            app.TmData(:) = app.RoomTemp;
            app.PWMData = zeros(1,app.ValuesToTrack);
            app.Time = zeros(1,app.ValuesToTrack);
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
                       dt = .01;
            while app.RunSwitch.Value == 1
                lastIndex = mod(app.Array_Start-2,app.ValuesToTrack)+1; % use modulo to wrap indeies.

                app.PWMData(app.Array_Start) = app.PWMSlider.Value; %get the current PWM Slider Value
                app.Time(app.Array_Start)=app.Time(lastIndex)+dt; % update the time
                % solve dT/dt = -(T-T_room) +PWM/255*(heat_cool_mode)*enable
                % using forward euler
                
                if app.ManualButton.Value == 1
                % change in temperature (T) w time (t) equals
                % heat loss to room -(T-T_room) plus heat from TEC 
                % solve dT/dt = -(T-T_room) +PWM/255*(heat_cool_mode)*enable
                % PWM is divided by 255 just to make numbers nice. Think of
                % it as a thermal mass coefficient 
                % using forward euler
                app.TData(app.Array_Start)=app.TData(lastIndex)+dt*(app.RoomTemp-app.TData(lastIndex)+app.TECpowerSwitch.Value*app.PWMSlider.Value/255*(app.HCSwitch.Value));
                end

                if app.ProportionalButton.Value == 1
                % change in temperature (T) w time (t) equals
                % heat loss to room 
                % -1*HeatLossConst*(T-T_room) 
                % plus heat from TEC 
                % PWM/10*(heat_cool_mode)*enable
                % Here, in proportional control, PWM = K_p * (T_set - T)
                % Furthermore, the PWM is limited to the range of [0,255],
                % like with an Arduino
                % solve dT/dt = -1*HeatLossConst*(T-T_room) + PWM/10*(heat_cool_mode)*enable
                % PWM is divided by 10 to make numbers nice. Think of
                % it as a thermal mass coefficient 
                % using forward euler
                app.PWM_Now =  round(min(app.K_pSlider.Value * abs( (app.SetTemperatureEditField.Value - app.TData(lastIndex)) ), 255));
                app.PWMSlider.Value = app.PWM_Now;
                app.HCSwitch.Value = sign(app.SetTemperatureEditField.Value - app.TData(lastIndex));
                coolingTerm = app.RoomTemp-app.TData(lastIndex);
                app.TData(app.Array_Start)=app.TData(lastIndex)+dt*(app.HeatLossConstant.Value*coolingTerm+app.TECpowerSwitch.Value*app.PWM_Now/10*(app.HCSwitch.Value));
                end  

                if app.ThermalMassButton.Value == 1
                % Temperature of the TEC (T)
                % Temperature of the mass (T_m)
                % mass changes temperature through conduction from TEC
                % change in temperature of mass (T_m) w time (t) equals
                % dT_m/dt = (T - T_m) / m
                % The bigger the mass, the slower the temperature change
                % and the bigger the phase lag between T_m and T (the TEC temperature).

                % The change in temperature of TEC (T) w time (t) equals
                % heat loss to room 
                % -1*HeatLossConst*(T_m-T_room) 
                % plus heat from TEC 
               
                % Here, in proportional control, PWM = K_p * (T_set - T_m)
                % Furthermore, the PWM is limited to the range of [0,255], like with an Arduino
                % solve dT/dt = -1*HeatLossConst*(T_m - T_room) + PWM/10*(heat_cool_mode)*enable

                % using forward euler
                PWM_Term = app.SetTemperatureEditField_2.Value - app.TmData(lastIndex); % temp diff for prop control: T_set - T_meas
                app.PWM_Now =  round(min(app.K_pSlider_2.Value * abs( (PWM_Term) ), 255));  % PWM for proportional control
                app.PWMSlider.Value = app.PWM_Now;   % set PWM slider
                app.HCSwitch.Value = sign(PWM_Term);  % set heat or cool switch
                coolingTerm = app.RoomTemp-app.TmData(lastIndex);  % heat loss / gain to room is proportional to T_m - T_room
                
                % update TEC temperature
                app.TData(app.Array_Start)=app.TData(lastIndex)+dt*(app.HeatLossConstant_2.Value*coolingTerm + app.TECpowerSwitch.Value*(app.PWM_Now/10)*app.HCSwitch.Value);
                
                % update temperature of the block of metal where the thermistor is located
                massTempTerm = app.TData(lastIndex) - app.TmData(lastIndex);
                app.TmData(app.Array_Start)=app.TmData(lastIndex)+dt*(app.mass1Slider.Value*massTempTerm);
                end  

                %  heat/cool lamp               
                if app.HCSwitch.Value == 1
                    app.HeatCoolLamp.Color = [1 0 0]; % red - heat
                    elseif app.HCSwitch.Value == -1
                    app.HeatCoolLamp.Color = [0 1 0]; % green - cool
                end

                % update where the start now is
                app.Array_Start=app.Array_Start+1;
                % wrap it if we've gone past the end
                if app.Array_Start>app.ValuesToTrack
                    app.Array_Start = 1;
                end

                app.TECtempEditField.Value = app.TData(lastIndex);
                app.ThermistortempEditField.Value = app.TmData(lastIndex);
                app.PWMEditField.Value = app.PWMSlider.Value;
                %plotting loop and data saving commands
                XData = [app.Time(app.Array_Start:end),app.Time(1:app.Array_Start-1)];
                YData = [app.PWMData(app.Array_Start:end),app.PWMData(1:app.Array_Start-1)];
                plot(app.PWMPlot,XData,YData);
                % Plot PWM from [0, 255]
                axis(app.PWMPlot,[min(app.Time),max(app.Time)+eps,0,255])
                % autoscale PWM axis
   %             axis(app.PWMPlot,[min(app.Time),max(app.Time)+eps,min(app.PWMData)-1,max(app.PWMData)+1])

                % Plot TEC temperature
                YData = [app.TData(app.Array_Start:end),app.TData(1:app.Array_Start-1)];

                plot(app.TPlot,XData,YData);

                axis(app.TPlot,[min(app.Time),max(app.Time),min(app.TData)-.1,max(app.TData)+.1])
                
                % Plot mass temperature
              if app.ThermalMassButton.Value == 1
                
                YData = [app.TmData(app.Array_Start:end),app.TmData(1:app.Array_Start-1)];

                YDataTEC = [app.TData(app.Array_Start:end),app.TData(1:app.Array_Start-1)];
                
                plot(app.TmPlot,XData,YData,XData,YDataTEC);
                axis(app.TmPlot,[min(app.Time),max(app.Time),min([min(app.TmData) min(app.TData)])-.1,max([max(app.TmData) max(app.TData)])+.1])
              end

                drawnow
                                
                pause(dt);

            end            
        end

        % Button down function: ManualTab
        function ManualTabButtonDown(app, event)
             app.TmPlot.Visible = 'off';
        end

        % Value changed function: K_pSlider
        function K_pSliderValueChanged(app, event)
            app.K_pNumber.Value = app.K_pSlider.Value;
            
        end

        % Value changed function: HeatLossConstant
        function HeatLossConstantValueChanged(app, event)
            app.HeatLossNumber.Value = app.HeatLossConstant.Value;
            
        end

        % Value changed function: K_pSlider_2
        function K_pSlider_2ValueChanged(app, event)
           app.K_pNumber_2.Value = app.K_pSlider_2.Value;
        end

        % Value changed function: HeatLossConstant_2
        function HeatLossConstant_2ValueChanged(app, event)
            app.HeatLossNumber_2.Value = app.HeatLossConstant_2.Value;
            
        end

        % Button down function: ProportionalwmassTab
        function ProportionalwmassTabButtonDown(app, event)
            app.TmPlot.Visible = 'on';
        end

        % Button down function: Proportional
        function ProportionalButtonDown(app, event)
            app.TmPlot.Visible = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {588, 588};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {300, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunSwitchLabel = uilabel(app.LeftPanel);
            app.RunSwitchLabel.Text = 'Run';
            app.RunSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RunSwitch.ItemsData = [0 1];
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.RunSwitch.Value = 0;
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.ManualTab.ButtonDownFcn = createCallbackFcn(app, @ManualTabButtonDown, true);
            app.PWMSliderLabel = uilabel(app.ManualTab);
            app.PWMSliderLabel.Text = 'PWM';
            app.PWMSlider = uislider(app.ManualTab);
            app.PWMSlider.Limits = [0 255];
            app.PWMSlider.MajorTicks = [0 85 170 255];
            app.HCSwitchLabel = uilabel(app.ManualTab);
            app.HCSwitchLabel.Text = 'H/C';
            app.HCSwitch = uiswitch(app.ManualTab, 'slider');
            app.HCSwitch.Items = {'Heat', 'Cool'};
            app.HCSwitch.ItemsData = [1 -1];
            app.HCSwitch.Value = 1;
            app.Proportional = uitab(app.TabGroup);
            app.Proportional.Title = 'Proportional';
            app.Proportional.ButtonDownFcn = createCallbackFcn(app, @ProportionalButtonDown, true);
            app.K_pSliderLabel = uilabel(app.Proportional);
            app.K_pSliderLabel.Text = 'K_p';
            app.SetTemperatureEditFieldLabel = uilabel(app.Proportional);
            app.SetTemperatureEditFieldLabel.Text = 'Set Temperature';
            app.HeatLossLabel = uilabel(app.Proportional);
            app.HeatLossLabel.Text = 'Heat Loss';
            app.HeatLossNumber = uieditfield(app.Proportional, 'numeric');
            app.HeatLossNumber.Limits = [0 2];
            app.HeatLossNumber.ValueDisplayFormat = '%.1f';
            app.HeatLossNumber.Value = 1;
            app.HeatLossConstant = uislider(app.Proportional);
            app.HeatLossConstant.Limits = [0 2];
            app.HeatLossConstant.ValueChangedFcn = createCallbackFcn(app, @HeatLossConstantValueChanged, true);
            app.HeatLossConstant.MinorTicks = [];
            app.HeatLossConstant.Value = 1;
            app.K_pNumber = uieditfield(app.Proportional, 'numeric');
            app.K_pNumber.Limits = [0 200];
            app.K_pNumber.ValueDisplayFormat = '%.1f';
            app.K_pSlider = uislider(app.Proportional);
            app.K_pSlider.Limits = [0 200];
            app.K_pSlider.MajorTicks = [0 50 100 150 200];
            app.K_pSlider.ValueChangedFcn = createCallbackFcn(app, @K_pSliderValueChanged, true);
            app.K_pSlider.MinorTicks = [];
            app.SetTemperatureEditField = uieditfield(app.Proportional, 'numeric');
            app.SetTemperatureEditField.Limits = [-20 100];
            app.SetTemperatureEditField.Value = 25;
            app.ProportionalwmassTab = uitab(app.TabGroup);
            app.ProportionalwmassTab.Title = 'Proportional w mass';
            app.ProportionalwmassTab.ButtonDownFcn = createCallbackFcn(app, @ProportionalwmassTabButtonDown, true);
            app.SetTemperatureEditField_2Label = uilabel(app.ProportionalwmassTab);
            app.SetTemperatureEditField_2Label.Text = 'Set Temperature';
            app.SetTemperatureEditField_2 = uieditfield(app.ProportionalwmassTab, 'numeric');
            app.SetTemperatureEditField_2.Limits = [-20 100];
            app.SetTemperatureEditField_2.Value = 25;
            app.K_pSlider_2Label = uilabel(app.ProportionalwmassTab);
            app.K_pSlider_2Label.Interpreter = 'tex';
            app.K_pSlider_2Label.Text = 'K_p';
            app.K_pSlider_2 = uislider(app.ProportionalwmassTab);
            app.K_pSlider_2.Limits = [0 200];
            app.K_pSlider_2.MajorTicks = [0 50 100 150 200];
            app.K_pSlider_2.ValueChangedFcn = createCallbackFcn(app, @K_pSlider_2ValueChanged, true);
            app.K_pSlider_2.MinorTicks = [];
            app.K_pSlider_2.Value = 10;
            app.K_pNumber_2 = uieditfield(app.ProportionalwmassTab, 'numeric');
            app.K_pNumber_2.Limits = [0 200];
            app.K_pNumber_2.ValueDisplayFormat = '%.1f';
            app.K_pNumber_2.Value = 10;
            app.HeatLossConstant_2 = uislider(app.ProportionalwmassTab);
            app.HeatLossConstant_2.Limits = [0 2];
            app.HeatLossConstant_2.ValueChangedFcn = createCallbackFcn(app, @HeatLossConstant_2ValueChanged, true);
            app.HeatLossConstant_2.MinorTicks = [];
            app.HeatLossConstant_2.Value = 1;
            app.HeatLossLabel_2 = uilabel(app.ProportionalwmassTab);
            app.HeatLossLabel_2.Text = 'Heat Loss';
            app.HeatLossNumber_2 = uieditfield(app.ProportionalwmassTab, 'numeric');
            app.HeatLossNumber_2.Limits = [0 2];
            app.HeatLossNumber_2.ValueDisplayFormat = '%.1f';
            app.HeatLossNumber_2.Value = 1;
            app.mass1SliderLabel = uilabel(app.ProportionalwmassTab);
            app.mass1SliderLabel.Interpreter = 'tex';
            app.mass1SliderLabel.Text = 'mass^{-1}';
            app.mass1Slider = uislider(app.ProportionalwmassTab);
            app.mass1Slider.Limits = [0 10];
            app.mass1Slider.MinorTicks = [];
            app.mass1Slider.Value = 10;
            app.TECpowerSwitchLabel = uilabel(app.LeftPanel);
            app.TECpowerSwitchLabel.Text = 'TEC power';
            app.TECpowerSwitch = uiswitch(app.LeftPanel, 'slider');
            app.TECpowerSwitch.ItemsData = [0 1];
            app.TECpowerSwitch.Value = 0;
            app.ControlButtonGroup = uibuttongroup(app.LeftPanel);
            app.ControlButtonGroup.Title = 'Control';
            app.ManualButton = uitogglebutton(app.ControlButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.ProportionalButton = uitogglebutton(app.ControlButtonGroup);
            app.ProportionalButton.Text = 'Proportional';
            app.ThermalMassButton = uitogglebutton(app.ControlButtonGroup);
            app.ThermalMassButton.Text = 'Thermal mass';
            app.TECtempEditFieldLabel = uilabel(app.LeftPanel);
            app.TECtempEditFieldLabel.Text = 'TEC temp';
            app.TECtempEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TECtempEditField.ValueDisplayFormat = '%.1f';
            app.PWMEditFieldLabel = uilabel(app.LeftPanel);
            app.PWMEditFieldLabel.Text = 'PWM';
            app.PWMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PWMEditField.ValueDisplayFormat = '%.1f';
            app.HeatCoolLampLabel = uilabel(app.LeftPanel);
            app.HeatCoolLampLabel.Text = 'Heat/Cool';
            app.HeatCoolLamp = uilamp(app.LeftPanel);
            app.ThermistortempEditFieldLabel = uilabel(app.LeftPanel);
            app.ThermistortempEditFieldLabel.Text = {'Thermistor'; 'temp'};
            app.ThermistortempEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThermistortempEditField.ValueDisplayFormat = '%.1f';
            app.RightPanel = uipanel(app.GridLayout);
            app.TPlot = uiaxes(app.RightPanel);
            title(app.TPlot, 'TEC temperature (C)')
            xlabel(app.TPlot, 'time')
            ylabel(app.TPlot, 'T (C)')
            zlabel(app.TPlot, 'Z')
            app.PWMPlot = uiaxes(app.RightPanel);
            title(app.PWMPlot, 'PWM')
            xlabel(app.PWMPlot, 'time')
            ylabel(app.PWMPlot, 'PWM')
            zlabel(app.PWMPlot, 'Z')
            app.TmPlot = uiaxes(app.RightPanel);
            title(app.TmPlot, 'Thermistor temperature (C)')
            xlabel(app.TmPlot, 'time')
            ylabel(app.TmPlot, 'T (C)')
            zlabel(app.TmPlot, 'Z')
            app.TmPlot.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelingTECv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end